classdef CTScannerGUI < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                      matlab.ui.container.GridLayout
        CircleRadiusDropDown            matlab.ui.control.DropDown
        CircleRadiusDropDownLabel       matlab.ui.control.Label
        RectangleDimensionsDropDown     matlab.ui.control.DropDown
        RectangleDimensionsDropDownLabel  matlab.ui.control.Label
        RectangleConfigurationSizeLabel  matlab.ui.control.Label
        CTSignalIntensityGraphLabel     matlab.ui.control.Label
        RunCompareContrastDifferenceButton  matlab.ui.control.Button
        SaveSIImageButton               matlab.ui.control.Button
        IterationsSpinner_2             matlab.ui.control.Spinner
        IterationsSpinner_2Label        matlab.ui.control.Label
        AngleSpinner_2                  matlab.ui.control.Spinner
        AngleSpinner_2Label             matlab.ui.control.Label
        SelectSampleTypeButtonGroup     matlab.ui.container.ButtonGroup
        IterativeReconstructionSIButton  matlab.ui.control.RadioButton
        FilteredBackprokectionSIButton  matlab.ui.control.RadioButton
        RadonSpaceSIButton              matlab.ui.control.RadioButton
        OriginalImageSIButton           matlab.ui.control.RadioButton
        IterationsSpinner               matlab.ui.control.Spinner
        IterationsSpinnerLabel          matlab.ui.control.Label
        AngleSpinner                    matlab.ui.control.Spinner
        AngleSpinnerLabel               matlab.ui.control.Label
        GeneratePhamtomDropDown         matlab.ui.control.DropDown
        GeneratePhamtomDropDownLabel    matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        CompareContrastDifferenceButton  matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        SelectSignalIntensitySIandContrastButtonGroup  matlab.ui.container.ButtonGroup
        IterativeReconstructionButton   matlab.ui.control.RadioButton
        RadonandInverseRadonSIButton    matlab.ui.control.RadioButton
        RadonTransformationSIButton     matlab.ui.control.RadioButton
        OriginalSIandContrastButton     matlab.ui.control.RadioButton
        ImageReconstructionLabel        matlab.ui.control.Label
        PhatomImageLabel                matlab.ui.control.Label
        VirtualCTScannerLabel           matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: GeneratePhamtomDropDown
        function GeneratePhamtomDropDownOpening(comp, event)
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(comp, event)
            size = [256,256];
            app.phantom_canvas = zeros(size);

            cp = [size(1)/2 , size(2)/2];
            phantom_selection = app.GeneratePhantomDropDown.Value;

            if(strcmp(phantom_selection, 'Circle Phantom'))
                radius_value = app.CircleRadiiDropDown.Value;

                E1 = [1.0, 0.9, 0.9, 0, 0, 0;
                      -0.8, 0.8999, 0.8999, 0, 0, 0;
                      0.8, 0.2, 0.2, 0, 0, 0
                      -0.2, 0.06, 0.06, -0.11, 0.5, 0;
                      0.2, 0.05, 0.05, -0.11, 0.5, 0;
                      -0.2, 0.12, 0.12, -0.60, -0.1, 0;
                      0.2, 0.11, 0.11, -0.60, -0.1, 0;
                      -0.2, 0.17, 0.17, 0, -0.55, 0;
                      0.2, 0.16, 0.16, 0, -0.55, 0;
                ];
                
                if strcmpi(radius_value, '20%')
                    E1 = [1.0, 0.9, 0.9, 0, 0, 0;
                          -0.8, 0.8999, 0.8999, 0, 0, 0;
                          0.8, 0.2, 0.2, 0, 0, 0
                          -0.2, 0.06 * 1.2, 0.06 * 1.2, -0.11, 0.5, 0;
                          0.2, 0.05 * 1.2, 0.05 * 1.2, -0.11, 0.5, 0;
                          -0.2, 0.12 * 1.2, 0.12 * 1.2, -0.60, -0.1, 0;
                          0.2, 0.11 * 1.2, 0.11 * 1.2, -0.60, -0.1, 0;
                          -0.2, 0.17 * 1.2, 0.17 * 1.2, 0, -0.55, 0;
                          0.2, 0.16 * 1.2, 0.16 * 1.2, 0, -0.55, 0;
                    ];
                end

                [~, A2] = phantom(E1, 256);
                P1 = phantom(A2, 256);
                app.phantom_canvas = P1;
        
            elseif(strcmp(phantom_selection, 'Rectangular Phantom'))
                width = 0;%5*app.WidthSlider.Value;
                length = 0;%5*app.LengthSlider_2.Value;
                
                %rectangle dimension drop-down options
                dimension_value = app.RectangleDimensionsDropDown.Value;

                if strcmp(dimension_value, '60x30')
                    length = 60;
                    width = 30;
                elseif strcmp(dimension_value, '45x45')
                    length = 45;
                    width = 45;
                elseif strcmp(dimension_value, '30x60')
                    length = 30;
                    width = 60;
                end
                
                % console = ['Width: ', num2str(width), 'Length: ', num2str(length)];
                % disp(console);

                r_size = floor([width*2 length*2]);

                % get user input - rectangular size 
                % r_para[width, height]
                r_para = r_size;
        
                % x-axis = height
                % height x-axis starting point
                x1 = floor(cp(2) - r_para(2)/2);
                % height x-axis ending point
                x2 = x1 + r_para(2);
                
                % y-axis = width
                % width y-axix starting point
                y1 = floor(cp(1) - r_para(1)/2);
                % width y-axix ending point
                y2 = y1 + r_para(1);
        
                % draw the squre
                app.phantom_canvas(x1 : x2, y1 : y2) = 256;
                    
            else
                app.phantom_canvas = phantom(256);
            end

            imwrite(app.phantom_canvas,'./test.png');

            % a = imread('./test.png');
            imshow(app.phantom_canvas,'parent', app.PhantomImage);         
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(comp, event)
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying component
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1002 648];
            comp.BackgroundColor = [0.902 0.902 0.902];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes2 = uiaxes(comp.GridLayout);
            xlabel(comp.UIAxes2, 'X')
            ylabel(comp.UIAxes2, 'Y')
            zlabel(comp.UIAxes2, 'Z')
            comp.UIAxes2_2 = uiaxes(comp.GridLayout);
            xlabel(comp.UIAxes2_2, 'X')
            ylabel(comp.UIAxes2_2, 'Y')
            zlabel(comp.UIAxes2_2, 'Z')
            comp.UIAxes2_3 = uiaxes(comp.GridLayout);
            xlabel(comp.UIAxes2_3, 'X')
            ylabel(comp.UIAxes2_3, 'Y')
            zlabel(comp.UIAxes2_3, 'Z')
            comp.SaveButton_2 = uibutton(comp.GridLayout, 'push');
            comp.SaveButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveButton_2Pushed, true);
            comp.SaveButton_2.Text = 'Save';
            comp.VirtualCTScannerLabel = uilabel(comp.GridLayout);
            comp.VirtualCTScannerLabel.Text = 'Virtual CT Scanner';
            comp.PhatomImageLabel = uilabel(comp.GridLayout);
            comp.PhatomImageLabel.Text = 'Phatom Image';
            comp.ImageReconstructionLabel = uilabel(comp.GridLayout);
            comp.ImageReconstructionLabel.Text = 'Image Reconstruction';
            comp.SelectSignalIntensitySIandContrastButtonGroup = uibuttongroup(comp.GridLayout);
            comp.SelectSignalIntensitySIandContrastButtonGroup.Title = 'Select Signal Intensity (SI) and Contrast';
            comp.OriginalSIandContrastButton = uiradiobutton(comp.SelectSignalIntensitySIandContrastButtonGroup);
            comp.OriginalSIandContrastButton.Text = 'Original SI and Contrast';
            comp.OriginalSIandContrastButton.Value = true;
            comp.RadonTransformationSIButton = uiradiobutton(comp.SelectSignalIntensitySIandContrastButtonGroup);
            comp.RadonTransformationSIButton.Text = 'Radon Transformation SI';
            comp.RadonandInverseRadonSIButton = uiradiobutton(comp.SelectSignalIntensitySIandContrastButtonGroup);
            comp.RadonandInverseRadonSIButton.Text = 'Radon and Inverse Radon SI';
            comp.IterativeReconstructionButton = uiradiobutton(comp.SelectSignalIntensitySIandContrastButtonGroup);
            comp.IterativeReconstructionButton.Text = 'Iterative Reconstruction';
            comp.SaveButton = uibutton(comp.GridLayout, 'push');
            comp.SaveButton.Text = 'Save';
            comp.CompareContrastDifferenceButton = uibutton(comp.GridLayout, 'push');
            comp.CompareContrastDifferenceButton.Text = 'Compare Contrast Difference';
            comp.GenerateButton = uibutton(comp.GridLayout, 'push');
            comp.GenerateButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @GenerateButtonPushed, true);
            comp.GenerateButton.Text = 'Generate';
            comp.GeneratePhamtomDropDownLabel = uilabel(comp.GridLayout);
            comp.GeneratePhamtomDropDownLabel.Text = 'Generate Phamtom';
            comp.GeneratePhamtomDropDown = uidropdown(comp.GridLayout);
            comp.GeneratePhamtomDropDown.Items = {'Circle Phatom', 'Rectangle Phatom', 'Original Phatom'};
            comp.GeneratePhamtomDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @GeneratePhamtomDropDownOpening, true);
            comp.GeneratePhamtomDropDown.Value = 'Circle Phatom';
            comp.AngleSpinnerLabel = uilabel(comp.GridLayout);
            comp.AngleSpinnerLabel.Text = 'Angle';
            comp.AngleSpinner = uispinner(comp.GridLayout);
            comp.IterationsSpinnerLabel = uilabel(comp.GridLayout);
            comp.IterationsSpinnerLabel.Text = 'Iterations';
            comp.IterationsSpinner = uispinner(comp.GridLayout);
            comp.SelectSampleTypeButtonGroup = uibuttongroup(comp.GridLayout);
            comp.SelectSampleTypeButtonGroup.Title = 'Select Sample Type';
            comp.OriginalImageSIButton = uiradiobutton(comp.SelectSampleTypeButtonGroup);
            comp.OriginalImageSIButton.Text = 'Original Image SI';
            comp.OriginalImageSIButton.Value = true;
            comp.RadonSpaceSIButton = uiradiobutton(comp.SelectSampleTypeButtonGroup);
            comp.RadonSpaceSIButton.Text = 'Radon Space SI';
            comp.FilteredBackprokectionSIButton = uiradiobutton(comp.SelectSampleTypeButtonGroup);
            comp.FilteredBackprokectionSIButton.Text = 'Filtered Backprokection SI';
            comp.IterativeReconstructionSIButton = uiradiobutton(comp.SelectSampleTypeButtonGroup);
            comp.IterativeReconstructionSIButton.Text = 'Iterative Reconstruction SI';
            comp.AngleSpinner_2Label = uilabel(comp.GridLayout);
            comp.AngleSpinner_2Label.Text = 'Angle';
            comp.AngleSpinner_2 = uispinner(comp.GridLayout);
            comp.IterationsSpinner_2Label = uilabel(comp.GridLayout);
            comp.IterationsSpinner_2Label.Text = 'Iterations';
            comp.IterationsSpinner_2 = uispinner(comp.GridLayout);
            comp.SaveSIImageButton = uibutton(comp.GridLayout, 'push');
            comp.SaveSIImageButton.Text = 'Save SI Image';
            comp.RunCompareContrastDifferenceButton = uibutton(comp.GridLayout, 'push');
            comp.RunCompareContrastDifferenceButton.Text = 'Run Compare Contrast Difference';
            comp.CTSignalIntensityGraphLabel = uilabel(comp.GridLayout);
            comp.CTSignalIntensityGraphLabel.Text = 'CT Signal Intensity Graph';
            comp.RectangleConfigurationSizeLabel = uilabel(comp.GridLayout);
            comp.RectangleConfigurationSizeLabel.Text = 'Rectangle Configuration Size';
            comp.RectangleDimensionsDropDownLabel = uilabel(comp.GridLayout);
            comp.RectangleDimensionsDropDownLabel.Text = 'Rectangle Dimensions';
            comp.RectangleDimensionsDropDown = uidropdown(comp.GridLayout);
            comp.RectangleDimensionsDropDown.Items = {'60x30', '45x45', '30x60'};
            comp.RectangleDimensionsDropDown.Value = '60x30';
            comp.CircleRadiusDropDownLabel = uilabel(comp.GridLayout);
            comp.CircleRadiusDropDownLabel.Text = 'Circle Radius';
            comp.CircleRadiusDropDown = uidropdown(comp.GridLayout);
            comp.CircleRadiusDropDown.Items = {'20%'};
            comp.CircleRadiusDropDown.Value = '20%';
        end
    end
end